classdef ComPortComms < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        aEditField                 matlab.ui.control.EditField
        aEditFieldLabel            matlab.ui.control.Label
        GridLayout                 matlab.ui.container.GridLayout
        BaudRate                   matlab.ui.control.EditField
        SpeedLabel                 matlab.ui.control.Label
        packetsLoaded              matlab.ui.control.EditField
        PotenciometervalueLabel_2  matlab.ui.control.Label
        TX                         matlab.ui.control.Button
        CPUtempGauge               matlab.ui.control.Gauge
        CPUtempGaugeLabel          matlab.ui.control.Label
        ClearButton_tx             matlab.ui.control.Button
        xDataEditField_tx          matlab.ui.control.EditField
        dataLabel                  matlab.ui.control.Label
        iDEditField_tx             matlab.ui.control.EditField
        iDEditField_2Label         matlab.ui.control.Label
        PotenciometerField         matlab.ui.control.EditField
        PotenciometervalueLabel    matlab.ui.control.Label
        Alive                      matlab.ui.control.EditField
        AlivetimerEditFieldLabel   matlab.ui.control.Label
        PortField                  matlab.ui.control.EditField
        PortEditFieldLabel         matlab.ui.control.Label
        xDataEditField_rx          matlab.ui.control.EditField
        DataLabel                  matlab.ui.control.Label
        iDEditField_rx             matlab.ui.control.EditField
        iDEditFieldLabel           matlab.ui.control.Label
        ReceiveLabel               matlab.ui.control.Label
        TransmitLabel              matlab.ui.control.Label
        ClearButton_rx             matlab.ui.control.Button
        CloseButton                matlab.ui.control.Button
        ConnectionStatus           matlab.ui.control.Lamp
        PlatformSwitch             matlab.ui.control.Switch
        LoadButton                 matlab.ui.control.Button
        SendButton                 matlab.ui.control.Button
        OpenButton                 matlab.ui.control.Button
        Graph                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        s % seriovy port objekt
        s_port = '/dev/ttyACM0' % seriovy port
        Timer % casovac pro vzorkovani
        connected = 0 % status pripojeni
        comms_handler; % instance CommsBuffer tridy

        iD string % id nactenych dat
        nData string % delka nacitanych dat
        xData string % nacitana data

        x = -1.75:0.01:1.75; % pro graf
        a; % koeficient pro graf
        

        fs; % vzorkovaci frekvence
        t; % vzorkovaci perioda
        n; % pocet vzorku
    
        xGraphPlot = 0:0.1:10;
        yGraphPlot = zeros(1,101);

        singleSample;

        total_loaded = 0;
    end
    
    methods (Access = private)
        
        function update_connection_status(app)
            % aktualizuje diodu statusu aktualniho spojeni
            switch app.connected
                case 1
                    app.ConnectionStatus.Color = 'green';
                case 0
                    app.ConnectionStatus.Color = [0.90 0.90 0.90];
                case -1
                    app.ConnectionStatus.Color = 'red';
            end
        end
        
        function TimerPeriodCallback(app, ~, ~)

            % posun prvku a pridani noveho na konec
            app.yGraphPlot = [app.yGraphPlot(2:end), app.singleSample];

            % vykresleni grafu
            plot(app.potenciometerGraph, app.yGraphPlot);
            
            % nastaveni meze
            app.potenciometerGraph.YLim = ([0 4096]);
        end
        
    end
    
    methods (Access = public)
        
        function processReceivedData(app, id, elements, dataBuffer)
            switch id
                case 0
                    for e = 1:elements
                        switch dataBuffer(e).data_id
                            case 1
                                app.Alive.Value = ...
                                    string(dataBuffer(e).data);
                            case 2
                                app.CPUtempGauge.Value = ...
                                    dataBuffer(e).data;
                            case 20
                                app.iDEditField_rx.Value = ...
                                    string(dataBuffer(e).data_id);
                                app.xDataEditField_rx.Value = ...
                                    string(dataBuffer(e).data);
                            case 23
                                app.PotenciometerField.Value = ...
                                    string(dataBuffer(e).data);
                            case 54
                                app.total_loaded = app.total_loaded + 1;
                                app.packetsLoaded.Value = string(app.total_loaded);

                        end
                    end
                otherwise
                    error("Unsupported buffer id: %d", id);
            end
            app.total_loaded = 0;
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % vzorkovani
            app.fs = 10; % vzorkovaci frekvence - sekunda
            app.t = 1 / app.fs; % vzorkovaci perioda
            app.n = 10 * app.fs; % pocet vzorku pro deset sekund

            % inicializace casovace
            app.Timer = timer('ExecutionMode','fixedRate', 'Period', app.t, 'TimerFcn', @app.TimerPeriodCallback); 

            % init tx_buffer
            app.comms_handler = comms_data_rxtx();
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            try
                if ~app.comms_handler.is_connected()
                    % napojeni na serial port
                    
                    % zapnuti casovace
                    app.Timer.start();
                    
                    % nastaveni rychlosti, target 230400 (2*115200)
                    %app.s = serialport(app.s_port, 4*115200);
                    %app.s = serialport(app.s_port, 115200, "FlowControl", "hardware");
                    
                    %configureTerminator(app.s, "CR/LF");
                    %configureCallback(app.s ,"terminator", @app.SerialPortCallBack010);
                    % app.s = serialport(app.s_port, 115200, ...
                    %     'DataBits', 8, ...
                    %     'StopBits', 1, ...
                    %     'Parity', 'none', ...
                    %     'FlowControl', 'hardware');
                    % flush(app.s)
                    % configureTerminator(app.s, "CR/LF");
                    % configureCallback(app.s, "terminator", @(src, event) SerialPortRxCallback(app, src, event));
                    
                    success = app.comms_handler.open_port(app.s_port, str2double(app.BaudRate.Value));
                    
                    if success
                        %app.comms_handler.configure_callback(@(src, event) app.comms_handler.handle_rx_callback(app, src, event));
                        app.comms_handler.configure_callback(app);
                        
                        % aktualizace statusu
                        app.connected = 1;
                        app.update_connection_status();
                        
                        % zmena tlacitek
                        app.OpenButton.Enable = "off";
                        app.CloseButton.Enable = "on";
                    else
                        error('Failed to open port');
                    end
                end
            catch ME

                % doslo k chybe, port nenexistuje / je jiz pouzivan
                disp(ME.message)
                app.ConnectionStatus.Color = 'red';
                app.Timer.stop();
                
                % aktualizace statusu
                app.connected = -1;
                app.update_connection_status();
            end 
        end

        % Button pushed function: SendButton
        function SendButtonPushed(app, event)
            idtx = str2num(app.iDEditField_tx.Value);
            xtx = str2num(app.xDataEditField_tx.Value);

            app.comms_handler = app.comms_handler.comms_append_int32(idtx,1,xtx);
            app.comms_handler.comms_send();            
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            % manualne vyvola nacteni dat
            [app.iD, app.nData, app.xData] = readDataSTM32(app.s);

            app.iDEditField_rx.Value = app.iD;
            app.xDataEditField_rx.Value = app.xData;
        end

        % Value changing function: iDEditField_rx
        function iDEditField_rxValueChanging(app, event)
            changingValue = event.Value;
        end

        % Value changed function: PlatformSwitch
        function PlatformSwitchValueChanged(app, event)
            % funkce predvyplnuje serial port

            value = app.PlatformSwitch.Value;
            
            if strcmp(value,'Linux')
                %disp('Linux')
                port = '/dev/ttyACM0';
            elseif strcmp(value,'Windows')
                %disp('Windows')
                port = 'COM5';
            end
            
            app.s_port = port;
            app.PortField.Value = port;
        end

        % Value changed function: PortField
        function PortFieldValueChanged(app, event)
            value = app.PortField.Value;
            
            app.s_port = value;
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            
            % ukonci spojeni
            app.Timer.stop();
            try
                app.connected = 0;
                app.update_connection_status();
                app.OpenButton.Enable = "on";
                app.CloseButton.Enable = "off";
                app.comms_handler.close_port();
            catch ME
                error('Chyba při zavírání portu: %s', ME.message);
            end
        end

        % Button pushed function: ClearButton_rx
        function ClearButton_rxPushed(app, event)
            app.iDEditField_rx.Value = '';
            app.xDataEditField_rx.Value = '';
        end

        % Button pushed function: ClearButton_tx
        function ClearButton_txPushed(app, event)
            app.iDEditField_tx.Value = "";
            app.xDataEditField_tx.Value = "";
        end

        % Button pushed function: TX
        function TXButtonPushed(app, event)
            % comms_append_int32(app,5,1,1);
            % comms_send(app)

            app.comms_handler = app.comms_handler.comms_append_int32(5,1,1);
            app.comms_handler.comms_send();
    
            % disp(buffer)
            % disp(length(buffer))
            % writeDataSTM32(app.s, buffer);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Graph = uiaxes(app.UIFigure);
            title(app.Graph, 'abs(x).^(2/3) + 0.9 .* (3.3 - abs(x).^2).^(1/2) .* sin(a .* pi .* abs(x))')
            xlabel(app.Graph, 'X')
            ylabel(app.Graph, 'Y')
            zlabel(app.Graph, 'Z')
            app.Graph.XLim = [-3 3];
            app.Graph.YLim = [-2 3];
            app.GridLayout = uigridlayout(app.UIFigure);
            app.OpenButton = uibutton(app.GridLayout, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.SendButton = uibutton(app.GridLayout, 'push');
            app.SendButton.ButtonPushedFcn = createCallbackFcn(app, @SendButtonPushed, true);
            app.SendButton.Text = 'Send';
            app.LoadButton = uibutton(app.GridLayout, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Enable = 'off';
            app.LoadButton.Visible = 'off';
            app.LoadButton.Tooltip = {'Can not load when nothing was received'};
            app.LoadButton.Text = 'Load';
            app.PlatformSwitch = uiswitch(app.GridLayout, 'slider');
            app.PlatformSwitch.Items = {'Linux', 'Windows'};
            app.PlatformSwitch.ValueChangedFcn = createCallbackFcn(app, @PlatformSwitchValueChanged, true);
            app.PlatformSwitch.Value = 'Linux';
            app.ConnectionStatus = uilamp(app.GridLayout);
            app.CloseButton = uibutton(app.GridLayout, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Enable = 'off';
            app.CloseButton.Text = 'Close';
            app.ClearButton_rx = uibutton(app.GridLayout, 'push');
            app.ClearButton_rx.ButtonPushedFcn = createCallbackFcn(app, @ClearButton_rxPushed, true);
            app.ClearButton_rx.Text = 'Clear';
            app.TransmitLabel = uilabel(app.GridLayout);
            app.TransmitLabel.Text = 'Transmit';
            app.ReceiveLabel = uilabel(app.GridLayout);
            app.ReceiveLabel.Text = 'Receive';
            app.iDEditFieldLabel = uilabel(app.GridLayout);
            app.iDEditFieldLabel.Text = 'iD';
            app.iDEditField_rx = uieditfield(app.GridLayout, 'text');
            app.iDEditField_rx.ValueChangingFcn = createCallbackFcn(app, @iDEditField_rxValueChanging, true);
            app.iDEditField_rx.Editable = 'off';
            app.DataLabel = uilabel(app.GridLayout);
            app.DataLabel.Text = 'Data';
            app.xDataEditField_rx = uieditfield(app.GridLayout, 'text');
            app.xDataEditField_rx.Editable = 'off';
            app.PortEditFieldLabel = uilabel(app.GridLayout);
            app.PortEditFieldLabel.Text = 'Port';
            app.PortField = uieditfield(app.GridLayout, 'text');
            app.PortField.ValueChangedFcn = createCallbackFcn(app, @PortFieldValueChanged, true);
            app.PortField.Value = '/dev/ttyACM0';
            app.AlivetimerEditFieldLabel = uilabel(app.GridLayout);
            app.AlivetimerEditFieldLabel.Text = 'Alive timer';
            app.Alive = uieditfield(app.GridLayout, 'text');
            app.Alive.Editable = 'off';
            app.Alive.Value = '0';
            app.PotenciometervalueLabel = uilabel(app.GridLayout);
            app.PotenciometervalueLabel.Text = 'Potentiometer ';
            app.PotenciometerField = uieditfield(app.GridLayout, 'text');
            app.PotenciometerField.Editable = 'off';
            app.iDEditField_2Label = uilabel(app.GridLayout);
            app.iDEditField_2Label.Text = 'iD';
            app.iDEditField_tx = uieditfield(app.GridLayout, 'text');
            app.iDEditField_tx.Value = '20';
            app.dataLabel = uilabel(app.GridLayout);
            app.dataLabel.Text = 'Data';
            app.xDataEditField_tx = uieditfield(app.GridLayout, 'text');
            app.xDataEditField_tx.Value = '54';
            app.ClearButton_tx = uibutton(app.GridLayout, 'push');
            app.ClearButton_tx.ButtonPushedFcn = createCallbackFcn(app, @ClearButton_txPushed, true);
            app.ClearButton_tx.Text = 'Clear';
            app.CPUtempGaugeLabel = uilabel(app.GridLayout);
            app.CPUtempGaugeLabel.Text = 'CPU temp';
            app.CPUtempGauge = uigauge(app.GridLayout, 'circular');
            app.CPUtempGauge.Limits = [-25 75];
            app.TX = uibutton(app.GridLayout, 'push');
            app.TX.ButtonPushedFcn = createCallbackFcn(app, @TXButtonPushed, true);
            app.TX.Text = 'toggle LED';
            app.PotenciometervalueLabel_2 = uilabel(app.GridLayout);
            app.PotenciometervalueLabel_2.Text = 'Sum packets';
            app.packetsLoaded = uieditfield(app.GridLayout, 'text');
            app.packetsLoaded.Editable = 'off';
            app.SpeedLabel = uilabel(app.GridLayout);
            app.SpeedLabel.Text = 'Speed';
            app.BaudRate = uieditfield(app.GridLayout, 'text');
            app.BaudRate.InputType = 'digits';
            app.BaudRate.Value = '115200';
            app.aEditFieldLabel = uilabel(app.UIFigure);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.UIFigure, 'text');
            app.aEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ComPortComms

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end